classdef leadLagGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Title                        matlab.ui.control.Label
        ParametersPanel              matlab.ui.container.Panel
        PhasedegLabel                matlab.ui.control.Label
        Phase                        matlab.ui.control.EditField
        DesiredFrequencyradsecLabel  matlab.ui.control.Label
        Freq                         matlab.ui.control.EditField
        EnterButton                  matlab.ui.control.Button
        ReadyLampLabel               matlab.ui.control.Label
        Ready                        matlab.ui.control.Lamp
        OptionsPanel                 matlab.ui.container.Panel
        SaveExitButton               matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        HelpButton                   matlab.ui.control.Button
        ControllerBodePanel          matlab.ui.container.Panel
        Bode_mag                     matlab.ui.control.UIAxes
        Bode_phase                   matlab.ui.control.UIAxes
        sqrtaswnLabel                matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        ssqrtawnLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
        G
        des
        phase;
        freq;
        order;
        edit_flag;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, G, des, EditMode)
           app.CallingApp = mainapp;
           app.G = G;
           app.des = des;
           app.edit_flag = EditMode;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            clead = Clead(str2double(app.Phase.Value),str2double(app.Freq.Value),1);
            [mag, phase_local, w0] =bode(clead);
            semilogx(app.Bode_mag,w0,squeeze(mag(1,1,:)));
            semilogx(app.Bode_phase,w0,squeeze(phase_local(1,1,:)));
            app.phase = str2double(app.Phase.Value);
            app.freq = str2double(app.Freq.Value);
            app.order = 1;
            
            if ~isempty(app.phase) && ~isempty(app.freq)
                app.Ready.Color = [0,1,0];
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            lead_flag = 0;
            updateLeadCont(app.CallingApp,app.G,app.des,app.phase,app.freq,app.order,lead_flag,app.edit_flag )
            delete(app);
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            lead_flag = 1;
            updateLeadCont(app.CallingApp,app.G,app.des,app.phase,app.freq,app.order,lead_flag,app.edit_flag )
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Lead  Controller';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.PhasedegLabel = uilabel(app.ParametersPanel);
            app.PhasedegLabel.Text = 'Phase [deg]:';
            app.Phase = uieditfield(app.ParametersPanel, 'text');
            app.DesiredFrequencyradsecLabel = uilabel(app.ParametersPanel);
            app.DesiredFrequencyradsecLabel.Text = 'Desired Frequency [rad/sec]:';
            app.Freq = uieditfield(app.ParametersPanel, 'text');
            app.EnterButton = uibutton(app.ParametersPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ReadyLampLabel = uilabel(app.ParametersPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.Ready = uilamp(app.ParametersPanel);
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.SaveExitButton = uibutton(app.OptionsPanel, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.HelpButton = uibutton(app.OptionsPanel, 'push');
            app.HelpButton.Text = 'Help';
            app.ControllerBodePanel = uipanel(app.UIFigure);
            app.ControllerBodePanel.Title = 'Controller Bode';
            app.Bode_mag = uiaxes(app.ControllerBodePanel);
            title(app.Bode_mag, 'Mag')
            xlabel(app.Bode_mag, 'Frequency [rad/sec]')
            ylabel(app.Bode_mag, 'Mag [dB]')
            app.Bode_phase = uiaxes(app.ControllerBodePanel);
            title(app.Bode_phase, 'Phase')
            xlabel(app.Bode_phase, 'Frequency[rad/sec]')
            ylabel(app.Bode_phase, 'Phase[deg]')
            app.sqrtaswnLabel = uilabel(app.UIFigure);
            app.sqrtaswnLabel.Text = 'sqrt(a)*s+wn';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-----------------';
            app.ssqrtawnLabel = uilabel(app.UIFigure);
            app.ssqrtawnLabel.Text = 's+sqrt(a)*wn';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = leadLagGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end