classdef loopShapingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoopShapingGUI                  matlab.ui.Figure
        ControllerPanel                 matlab.ui.container.Panel
        LeadLag                         matlab.ui.control.Button
        Notch                           matlab.ui.control.Button
        Gain                            matlab.ui.control.Button
        PID                             matlab.ui.control.Button
        AddPolesZeros                   matlab.ui.control.Button
        AutomaticDesigner               matlab.ui.control.Button
        ResetContoller                  matlab.ui.control.Button
        ControllerText                  matlab.ui.control.Label
        CurrentController               matlab.ui.control.Label
        EditControllerEditFieldLabel    matlab.ui.control.Label
        EditCont_num                    matlab.ui.control.EditField
        EditButton                      matlab.ui.control.Button
        DeleteControllerEditFieldLabel  matlab.ui.control.Label
        DelCont_num                     matlab.ui.control.EditField
        DeleteButton                    matlab.ui.control.Button
        ControllersList                 matlab.ui.control.ListBox
        Lag                             matlab.ui.control.Button
        NicholsBodePanel                matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        NicholsTab                      matlab.ui.container.Tab
        nicholsPlot                     matlab.ui.control.UIAxes
        CloseLoopBodeTab                matlab.ui.container.Tab
        CLbodePlot                      matlab.ui.control.UIAxes
        OpenLoopBodeTab                 matlab.ui.container.Tab
        OLbodePlot_mag                  matlab.ui.control.UIAxes
        OLbodePlot_phase                matlab.ui.control.UIAxes
        ControllerPolesandZerosPanel    matlab.ui.container.Panel
        zpkMap                          matlab.ui.control.UIAxes
        zpkTable_p                      matlab.ui.control.Table
        zpkTable_z                      matlab.ui.control.Table
        DragPolesandZerosButton         matlab.ui.control.Button
        OptionsPanel                    matlab.ui.container.Panel
        SaveController                  matlab.ui.control.Button
        LoadController                  matlab.ui.control.Button
        LoadPlant                       matlab.ui.control.Button
        Help                            matlab.ui.control.Button
        Discrete                        matlab.ui.control.Button
        savenExit                       matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        LoopShapingGUILabel             matlab.ui.control.Label
        QFTLogo                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        % dialogApp prop. is used to communicate between the main gui and
        % the controllers sub-guis
        DialogApp_gain;
        DialogApp_lead;
        DialogApp_notch;
        DialogApp_lag;
        DialogApp_pid;
        DialogApp_pz;
        newPoint;
        p;
        z;
    end
    
    properties (Access = public)
        G ;  % the controller from the w.s
        Controller;
        ini_cont; % the controller from w.s, used when edit / del or update the controller
        des; % des from the w.s
        % flags for chainging in the controller.
        gain_flag;
        lead_flag;
        lag_flag;
        pid_flag;
        notch_flag;
        pz_flag;
    end
    
    methods (Access = private)
        function [] = printGtext(app,G)
            Gtf = tf(G);
            syms s
            [num_P,den_P] = tfdata(Gtf);
            Gsym = vpa(poly2sym((num_P),s)/poly2sym((den_P),s),2);
            Gstr = print_sym_fraction(Gsym);
            app.CurrentController.Text = Gstr;
        end
    end
    
    methods (Access = public)
        function [] = printCLbode(app,G,des)
            wnom = des.nom.frequency;
            Cwnom = squeeze(freqresp(G,wnom)); % in complex plain
            Fwnom = squeeze(freqresp(tf(1),wnom)); % in complex plain
            Pnom = n2c(des.nom.response);
            Lnom = Cwnom.*Pnom;
            Tnom = Fwnom.*Lnom./(1+Lnom);
            T_mag = mag2db(abs(Tnom));
            semilogx(app.CLbodePlot,wnom,T_mag)
            app.nicholsPlot.Title.String = "Close Loop Bode";
            app.nicholsPlot.XLabel.String = "Frequency [rad/s]";
            app.nicholsPlot.XGrid = 'On';
            app.nicholsPlot.YGrid = 'On';
            axis(app.nicholsPlot,'tight')
            box(app.nicholsPlot,'on')
        end
        function [] = printOLbode(app,G,des)
            wnom = des.nom.frequency;
            Cwnom = squeeze(freqresp(G,wnom)); % in complex plain
            Fwnom = squeeze(freqresp(tf(1),wnom)); % in complex plain
            Pnom = n2c(des.nom.response);
            Lnom = Cwnom.*Pnom;
            L_mag = mag2db(abs(Lnom));
            L_phase = rad2deg(angle(Lnom));
            
            semilogx(app.OLbodePlot_mag,wnom,L_mag)
            app.nicholsPlot.Title.String = "Open Loop Bode - Mag";
            app.nicholsPlot.XLabel.String = "Frequency [rad/s]";
            app.nicholsPlot.XGrid = 'On';
            app.nicholsPlot.YGrid = 'On';
            axis(app.nicholsPlot,'tight')
            box(app.nicholsPlot,'on')
            
            semilogx(app.OLbodePlot_phase,wnom,L_phase)
            app.nicholsPlot.Title.String = "Open Loop Bode - Phase";
            app.nicholsPlot.XLabel.String = "Frequency [rad/s]";
            app.nicholsPlot.XGrid = 'On';
            app.nicholsPlot.YGrid = 'On';
            axis(app.nicholsPlot,'tight')
            box(app.nicholsPlot,'on')
        end
        function [] = printPZmap(app,G)
            [app.p,app.z] = pzmap(G);
            for i = 1 : length(app.p)
            plot(app.zpkMap,real(app.p(i)),imag(app.p(i)),'xr');
            hold(app.zpkMap,'on')
            end
            for i = 1 : length(app.z)
            plot(app.zpkMap,real(app.z(i)),imag(app.z(i)),'ob')
            hold(app.zpkMap,'on')
            end
            plot(app.zpkMap,[-10 10],[0 0],'k','linewidth',1)
            hold(app.zpkMap,'on')
            plot(app.zpkMap,[0 0],[-10 10],'k','linewidth',1)
            hold(app.zpkMap,'on')
            app.zpkMap.XMinorGrid = 'On';
            app.zpkMap.YMinorGrid = 'On';
            app.zpkMap.Title.String = "Pole - Zero Map";
            app.zpkMap.XLabel.String = "Real";
            app.zpkMap.YLabel.String = "Imaginary";
            
            %fill pz table
            app.zpkTable_p.Data = {};
            app.zpkTable_z.Data = {};
            if ~isempty(app.p)
                for i=1:length(app.p)
                    sys_poles(i,:) = {'pole' real(app.p(i)) imag(app.p(i))}; %sample data for each column
                end
                app.zpkTable_p.Data = [app.zpkTable_p.Data(:);sys_poles];
            end
            if ~isempty(app.z)
                for j=1:length(app.z)
                    sys_zeros(j,:) = {'zero' real(app.z(j)) imag(app.z(j))}; %sample data for each column
                end
                
                app.zpkTable_z.Data = [app.zpkTable_z.Data(:);sys_zeros];
            end
        end
        function [] = printCONTlist(app,type,reset,editMode)
            persistent i j
            if isempty(i)
                i = 1;
            end
            if isempty(j)
                j = 0;
            end
            
            if reset == 1
                i = 1;
            end
            
            if editMode ~= 0
                j = i;
                i = editMode;
            end
            
            if strcmp(type,'gain')
                app.ControllersList.Items(i+1) = {sprintf('#%d - gain',i+1)} ;
                if j ~= 0
                    i = j ;
                    j = 0;
                else
                    i = i+1;
                end
            end
            
            if strcmp(type,'lead')
                app.ControllersList.Items(i+1) = {sprintf('#%d - lead',i+1)} ;
                if j ~= 0
                    i = j ;
                    j = 0;
                else
                    i = i+1;
                end
            end
            
            if strcmp(type,'notch')
                app.ControllersList.Items(i+1) = {sprintf('#%d - notch',i+1)} ;
                if j ~= 0
                    i = j ;
                    j = 0;
                else
                    i = i+1;
                end
            end
            
            if strcmp(type,'lag')
                app.ControllersList.Items(i+1) = {sprintf('#%d - lag',i+1)} ;
                if j ~= 0
                    i = j ;
                    j = 0;
                else
                    i = i+1;
                end
            end
            
            if strcmp(type,'pid')
                app.ControllersList.Items(i+1) = {sprintf('#%d - pid',i+1)} ;
                if j ~= 0
                    i = j ;
                    j = 0;
                else
                    i = i+1;
                end
            end
            if strcmp(type,'pz')
                app.ControllersList.Items(i+1) = {sprintf('#%d - pz',i+1)} ;
                if j ~= 0
                    i = j ;
                    j = 0;
                else
                    i = i+1;
                end
            end
            
            
        end
        function [] = printHSbnd(app,des)
            
            app.nicholsPlot.cla;
            sepcs_num = length(des.bnd);
            freq_num = length(des.bnd(1).w);
            
            for i = 1 : 1 : sepcs_num
                for j = 1 : 1 : freq_num
                    bounds_data = des.bnd(i).c;
                    print_bound = bounds_data{j,1};
                    plot(app.nicholsPlot,real(print_bound),imag(print_bound),'LineWidth',1.3)
                    hold(app.nicholsPlot,'on')
                end
            end
            
            ngrid(app.nicholsPlot)
            app.nicholsPlot.Title.String = "Nichols Plot";
            app.nicholsPlot.XLabel.String = "Phase [deg]";
            app.nicholsPlot.YLabel.String = "Mag [dB]";
            hold(app.nicholsPlot,"on")
            axis(app.nicholsPlot,[-360 -180 -90 0])
            box(app.nicholsPlot,'on')
        end
        function [] = updateGnic(app,G,des)
            Lnom = series(des.nom,G);
            t_color = des.col;
            plot(app.nicholsPlot,Lnom.phase,Lnom.mag,'k','LineWidth',1.2);
            hold(app.nicholsPlot,"on")
            for k=1:length(des.tpl)
                str = sprintf(' %g',des.tpl(k).frequency);
                tk = qfr(des.tpl(k).template(1),des.tpl(k).frequency);
                Ltpl = series(tk,G);
                x_text = Ltpl.phase;
                y_text = Ltpl.mag;
                plot(app.nicholsPlot,Ltpl.phase,Ltpl.mag,'marker','square',...
                    'markeredgecolor','k','markerfacecolor',t_color(k,:));
                text(app.nicholsPlot,x_text,y_text,str,'clipping','on') % single space added
            end
            hold(app.nicholsPlot,"off")
            axis(app.nicholsPlot,'tight')
            box(app.nicholsPlot,'on')
            xlim(app.nicholsPlot,[-360 0]);
        end
        function [] = updateData(app,G,des)
            printCLbode(app,G,des);
            printOLbode(app,G,des)
            printHSbnd(app,des)
            updateGnic(app,G,des);
            printPZmap(app,G);
        end
        function [] = updateG(app,Controller)
            initial_cont = app.ini_cont;
            TotalGain = 1;
            TotalLead = 1;
            TotalLag = 1;
            TotalPID = 1;
            TotalNotch = 1;
            TotalPZ = 1;
            
            for i=1:length(Controller.gain)
                TotalGain = TotalGain * Controller.gain(i);
            end
            
            for i=1:length(Controller.lead)
                TotalLead = TotalLead * Controller.lead(i);
            end
            
            for i=1:length(Controller.lag)
                TotalLag = TotalLag * Controller.lag(i);
            end
            
            for i=1:length(Controller.pid)
                TotalPID = TotalPID * Controller.pid(i);
            end
            
            for i=1:length(Controller.notch)
                TotalNotch = TotalNotch * Controller.notch(i);
            end
            
            for i=1:length(Controller.pz)
                TotalPZ = TotalPZ * Controller.pz(i);
            end
            
            app.G = initial_cont*TotalGain*TotalLead*TotalLag*TotalPID*TotalNotch*TotalPZ;
            
        end
        function [] = updateGainCont(app,G,des,gain,gain_flag,EditMode)
            persistent i
            if isempty(i)
                i = 1;
            end
            if gain_flag == 1 && EditMode == 0
                app.Controller.gain(i) = gain;
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                printCONTlist(app,'gain',0,0);
                i = i+1;
            end
            if gain_flag == 1 && EditMode ~= 0 && EditMode ~= 100
                app.Controller.gain(EditMode) = gain;
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                printCONTlist(app,'gain',0,EditMode);
            end
            if gain_flag == 1 && EditMode ==100
                app.Controller.gain(i) = gain;
                updateG(app,app.Controller);
                updateData(app,app.G,des);
            end
        end
        function [] = updateLeadCont(app,G,des,phase,freq,order,lead_flag,EditMode)
            persistent i
            if isempty(i)
                i = 1;
            end
            if lead_flag == 1 && EditMode == 0
                app.Controller.lead(i) = Clead(phase,freq,order);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                printCONTlist(app,'lead',0,0);
                i = i+1;
            end
            if lead_flag == 1 && EditMode ~= 0 && EditMode ~= 100
                app.Controller.lead(EditMode) = Clead(phase,freq,order);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                printCONTlist(app,'lead',0,EditMode);
            end
            if lead_flag == 1 && EditMode == 100
                app.Controller.lead(i) = Clead(phase,freq,order);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
            end
        end
        function [] = updateNotchCont(app,G,des,w0n,w0d,zn,zd,notch_flag,EditMode)
            persistent i
            if isempty(i)
                i = 1;
            end
            if notch_flag == 1 && EditMode == 0
                app.Controller.notch(i) = Cnotch(w0n,w0d,zn,zd);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                printCONTlist(app,'notch',0,0);
                i = i+1;
            end
            if notch_flag == 1 && EditMode ~= 0 && EditMode ~= 100
                app.Controller.notch(EditMode) =  Cnotch(w0n,w0d,zn,zd);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                printCONTlist(app,'notch',0,EditMode);
            end
            if notch_flag == 1 && EditMode == 100
                app.Controller.notch(i) = Cnotch(w0n,w0d,zn,zd);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
            end
        end
        function [] = updateLagCont(app,G,des,freq,beta,lag_flag,EditMode)
            persistent i
            if isempty(i)
                i = 1;
            end
            if lag_flag == 1 && EditMode == 0
                app.Controller.lag(i) = Clag(freq,beta);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                printCONTlist(app,'lag',0,0);
                i = i+1;
            end
            if lag_flag == 1 && EditMode ~= 0 && EditMode ~= 100
                app.Controller.lag(EditMode) = Clag(freq,beta);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                printCONTlist(app,'lag',0,EditMode);
            end
            if lag_flag == 1 && EditMode == 100
                app.Controller.lag(i) = Clag(freq,beta);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
            end
        end
        function [] = updatePidCont(app,G,des,kp,ki,kd,tf,tau,flag,pid_flag,EditMode)
            persistent i
            if isempty(i)
                i = 1;
            end
            if pid_flag == 1 && EditMode == 0
                app.Controller.pid(i) = CPID(ki,kp,kd,tf,tau,flag);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                printCONTlist(app,'pid',0,0);
                i = i+1;
            end
            if pid_flag == 1 && EditMode ~= 0 && EditMode ~= 100
                app.Controller.pid(EditMode) = CPID(ki,kp,kd,tf,tau,flag);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                printCONTlist(app,'pid',0,EditMode);
            end
            if pid_flag == 1 && EditMode == 100
                app.Controller.pid(i) = CPID(ki,kp,kd,tf,tau,flag);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
            end
        end
        function [] = updatePZCont(app,G,des,a,b,c,d,flag,pz_flag,EditMode)
            
            persistent i
            if isempty(i)
                i = 1;
            end
            if pz_flag == 1 && EditMode == 0
                app.Controller.pz(i) = Cpz(a,b,c,d,flag);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                % here i need the interactive part of the pz map
                printCONTlist(app,'pz',0,0);
                i = i+1;
            end
            if pz_flag == 1 && EditMode ~= 0
                app.Controller.pz(EditMode) = Cpz(a,b,c,d,flag);
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                % here i need the interactive part of the pz map
                printCONTlist(app,'pz',0,EditMode);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Cont, Des)
            %print the controller on text
            app.G = Cont;
            app.ini_cont = Cont;
            app.des = Des;
            printGtext(app,app.G);
            app.ControllersList.Items(1) = {sprintf('#1 - Initial Controller - G')} ;
            %print pz map
            printPZmap(app,app.G);
            %print CL bode
            printCLbode(app,app.G,app.des);
            %print H-S bounds and the open loop on nichols chart
            printHSbnd(app,app.des);
            %print OL bode
            printOLbode(app,app.G,app.des);
            
            %plotting the open loop
            updateGnic(app,app.G,app.des)
            
            % define the controller
            app.Controller.gain(1) = 1;
            app.Controller.lead(1) = tf(1);
            app.Controller.lag(1) = tf(1);
            app.Controller.pid(1) = tf(1);
            app.Controller.notch(1) = tf(1);
            app.Controller.pz(1) = tf(1);
            clc
        end

        % Button pushed function: LoadPlant
        function LoadPlantButtonPushed(app, event)
            
        end

        % Button pushed function: ResetContoller
        function ResetContollerButtonPushed(app, event)
            % reset controller
            app.G = app.ini_cont;
            printCONTlist(app,'',1,0);
            app.ControllersList.Items = {};
            app.ControllersList.Items(1) = {sprintf('#1 - Initial Controller - G')} ;
            app.nicholsPlot.cla
            app.CLbodePlot.cla
            app.OLbodePlot_mag.cla
            app.OLbodePlot_phase.cla
            app.zpkMap.cla
            updateData(app,app.G,app.des)
            app.Controller.gain(:) = 1;
            app.Controller.lead(:) = tf(1);
            app.Controller.lag(:) = tf(1);
            app.Controller.pid(:) = tf(1);
            app.Controller.notch(:) = tf(1);
            app.Controller.pz(:) = tf(1);
        end

        % Size changed function: NicholsTab
        function NicholsTabSizeChanged(app, event)
            app.nicholsPlot.cla;
            printHSbnd(app,app.des);
            updateGnic(app,app.G,app.des);

        end

        % Size changed function: CloseLoopBodeTab
        function CloseLoopBodeTabSizeChanged(app, event)
            app.CLbodePlot.cla
            printCLbode(app,app.G,app.des);
        end

        % Size changed function: OpenLoopBodeTab
        function OpenLoopBodeTabSizeChanged(app, event)
            app.OLbodePlot_mag.cla
            app.OLbodePlot_phase.cla
            printOLbode(app,app.G,app.des);
        end

        % Button pushed function: LeadLag
        function LeadLagButtonPushed(app, event)
            editMode = 0;
            app.DialogApp_lead = leadLagGUI(app,app.G,app.des,editMode);
        end

        % Button pushed function: Gain
        function GainButtonPushed(app, event)
            editMode = 0;
            app.DialogApp_gain = gainGUI(app,app.G,app.des, editMode);
        end

        % Button pushed function: Lag
        function LagButtonPushed(app, event)
            editMode = 0;
            app.DialogApp_lag = lagGUI(app,app.G,app.des, editMode);
        end

        % Button pushed function: Notch
        function NotchButtonPushed(app, event)
            editMode = 0;
            app.DialogApp_notch = notchGUI(app,app.G,app.des, editMode);
        end

        % Button pushed function: PID
        function PIDButtonPushed(app, event)
            editMode = 0;
            app.DialogApp_pid = pidGUI(app,app.G,app.des, editMode);
        end

        % Button pushed function: AddPolesZeros
        function AddPolesZerosButtonPushed(app, event)
            editMode = 0;
            app.DialogApp_pz = pzGUI(app,app.G,app.des, editMode);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq()
        end

        % Button pushed function: savenExit
        function savenExitButtonPushed(app, event)
            assignin("base","G",app.G)
            closereq()
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            cont_num = str2double(app.DelCont_num.Value);
            cont_str = app.ControllersList.Items(cont_num);
            list = app.ControllersList.Items;
            %             app.ControllersList.Items{cont_num} = {sprintf("----")};
            
            str_4comp_gain = sprintf('#%d - gain',cont_num);
            str_4comp_lead = sprintf('#%d - lead',cont_num);
            str_4comp_lag = sprintf('#%d - lag',cont_num);
            str_4comp_pid = sprintf('#%d - pid',cont_num);
            str_4comp_notch = sprintf('#%d - notch',cont_num);
            str_4comp_zero = sprintf('#%d - pz',cont_num);
            
            if strcmp(string(cont_str),str_4comp_gain)
                app.ControllersList.Items(cont_num) = {sprintf('---Deleted Gain---')};
                place = findContPlace(app.Controller,list,cont_num,cont_str);
                app.Controller.gain(place) = 1;
                updateG(app,app.Controller);
                updateData(app,app.G,app.des);
            end
            
            if strcmp(string(cont_str),str_4comp_lead)
                app.ControllersList.Items(cont_num) = {sprintf('---Deleted Lead---')};
                place = findContPlace(app.Controller,list,cont_num,cont_str);
                app.Controller.lead(place) = tf(1);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des);
            end
            
            if strcmp(string(cont_str),str_4comp_notch)
                app.ControllersList.Items(cont_num) = {sprintf('---Deleted Notch---')};
                place = findContPlace(app.Controller,list,cont_num,cont_str);
                app.Controller.notch(place) = tf(1);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des);
            end
            
            if strcmp(string(cont_str),str_4comp_lag)
                app.ControllersList.Items(cont_num) = {sprintf('---Deleted Lag---')};
                place = findContPlace(app.Controller,list,cont_num,cont_str);
                app.Controller.lag(place) = tf(1);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des);
            end
            
            if strcmp(string(cont_str),str_4comp_pid)
                app.ControllersList.Items(cont_num) = {sprintf('---Deleted PID---')};
                place = findContPlace(app.Controller,list,cont_num,cont_str);
                app.Controller.pid(place) = tf(1);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des);
            end
            
            if strcmp(string(cont_str),str_4comp_zero)
                app.ControllersList.Items(cont_num) = {sprintf('---Deleted PZ---')};
                place = findContPlace(app.Controller,list,cont_num,cont_str);
                app.Controller.pz(place) = tf(1);
                updateG(app,app.Controller);
                updateData(app,app.G,app.des);
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            cont_num = str2double(app.EditCont_num.Value);
            cont_str = app.ControllersList.Items(cont_num);
            list = app.ControllersList.Items;
            str_4comp_gain = sprintf('#%d - gain',cont_num);
            str_4comp_lead = sprintf('#%d - lead',cont_num);
            str_4comp_lag = sprintf('#%d - lag',cont_num);
            str_4comp_pid = sprintf('#%d - pid',cont_num);
            str_4comp_notch = sprintf('#%d - notch',cont_num);
            str_4comp_zero = sprintf('#%d - pz',cont_num);
            
            if strcmp(string(cont_str),str_4comp_gain)
                place = findContPlace(app.Controller,list,cont_num,cont_str);
                app.DialogApp_gain = gainGUI(app,app.G,app.des,place);
            end
            if strcmp(string(cont_str),str_4comp_lead)
                place = findContPlace(app.Controller,list,cont_num,cont_str);
                app.DialogApp_lead = leadLagGUI(app,app.G,app.des,place);
            end
            if strcmp(string(cont_str),str_4comp_notch)
                place = findContPlace(app.Controller,list,cont_num,cont_str);
                app.DialogApp_notch = notchGUI(app,app.G,app.des,place);
            end
            if strcmp(string(cont_str),str_4comp_lag)
                place = findContPlace(app.Controller,list,cont_num,cont_str);
                app.DialogApp_lag = lagGUI(app,app.G,app.des,place);
            end
            if strcmp(string(cont_str),str_4comp_pid)
                place = findContPlace(app.Controller,list,cont_num,cont_str);
                app.DialogApp_pid = pidGUI(app,app.G,app.des,place);
            end
            
            if strcmp(string(cont_str),str_4comp_zero)
                place = findContPlace(app.Controller,list,cont_num,cont_str);
                app.DialogApp_pz = pzGUI(app,app.G,app.des,place);
            end
        end

        % Button pushed function: SaveController
        function SaveControllerButtonPushed(app, event)
            assignin("base","G",app.G)
            fprintf("Controller has been saved in the workspace, as G")
        end

        % Button pushed function: DragPolesandZerosButton
        function DragPolesandZerosButtonPushed(app, event)
            f = mouse_picks(app.G);
            uiwait(f);
            newPos = evalin("base",'newPos');
            type = newPos{3};
            fprintf(type);
        end

        % Window button down function: LoopShapingGUI
        function LoopShapingGUIWindowButtonDown(app, event)
%             persistent x y ind
%             pos = app.zpkMap.CurrentPoint; %pos
%             assignin("base","pole",app.p)
%             pause(0.001)
%             x = app.p(1);
%             fprintf("%f",x)
%             y = app.p(2);
%             [~,ind] = min(sum((x-pos(1)).^2+(y-pos(3)).^2,1));
%             x(ind) = pos(1);
%             y(ind) = pos(3);
%             [app.p, app.z] = pzmap(app.G);
%             app.p(1) = x(ind) + 1i*y(ind);
%             for i = 1 : length(app.p)
%                 plot(app.zpkMap,real(app.p(i)),imag(app.p(i)),'xr')
%                 hold(app.zpkMap,"on")
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoopShapingGUI = uifigure('Visible', 'off');
            app.LoopShapingGUI.Name = 'UI Figure';
            app.LoopShapingGUI.WindowButtonDownFcn = createCallbackFcn(app, @LoopShapingGUIWindowButtonDown, true);
            app.ControllerPanel = uipanel(app.LoopShapingGUI);
            app.ControllerPanel.Title = 'Controller';
            app.LeadLag = uibutton(app.ControllerPanel, 'push');
            app.LeadLag.ButtonPushedFcn = createCallbackFcn(app, @LeadLagButtonPushed, true);
            app.LeadLag.Text = 'Lead';
            app.Notch = uibutton(app.ControllerPanel, 'push');
            app.Notch.ButtonPushedFcn = createCallbackFcn(app, @NotchButtonPushed, true);
            app.Notch.Text = 'Notch/anti Notch';
            app.Gain = uibutton(app.ControllerPanel, 'push');
            app.Gain.ButtonPushedFcn = createCallbackFcn(app, @GainButtonPushed, true);
            app.Gain.Text = 'Gain';
            app.PID = uibutton(app.ControllerPanel, 'push');
            app.PID.ButtonPushedFcn = createCallbackFcn(app, @PIDButtonPushed, true);
            app.PID.Text = 'PID';
            app.AddPolesZeros = uibutton(app.ControllerPanel, 'push');
            app.AddPolesZeros.ButtonPushedFcn = createCallbackFcn(app, @AddPolesZerosButtonPushed, true);
            app.AddPolesZeros.Text = 'Add Poles/Zeros';
            app.AutomaticDesigner = uibutton(app.ControllerPanel, 'push');
            app.AutomaticDesigner.Text = 'Automatic Designer';
            app.ResetContoller = uibutton(app.ControllerPanel, 'push');
            app.ResetContoller.ButtonPushedFcn = createCallbackFcn(app, @ResetContollerButtonPushed, true);
            app.ResetContoller.Text = 'Reset Contoller';
            app.ControllerText = uilabel(app.ControllerPanel);
            app.ControllerText.Text = 'Current Contoller:';
            app.CurrentController = uilabel(app.ControllerPanel);
            app.CurrentController.Text = 'G(s) = 1';
            app.EditControllerEditFieldLabel = uilabel(app.ControllerPanel);
            app.EditControllerEditFieldLabel.Text = 'Edit Controller #:';
            app.EditCont_num = uieditfield(app.ControllerPanel, 'text');
            app.EditButton = uibutton(app.ControllerPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeleteControllerEditFieldLabel = uilabel(app.ControllerPanel);
            app.DeleteControllerEditFieldLabel.Text = 'Delete Controller #:';
            app.DelCont_num = uieditfield(app.ControllerPanel, 'text');
            app.DeleteButton = uibutton(app.ControllerPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.ControllersList = uilistbox(app.ControllerPanel);
            app.ControllersList.Items = {'#1 - Initial Controller - G'};
            app.ControllersList.Value = {};
            app.Lag = uibutton(app.ControllerPanel, 'push');
            app.Lag.ButtonPushedFcn = createCallbackFcn(app, @LagButtonPushed, true);
            app.Lag.Text = {'Lag'; ''};
            app.NicholsBodePanel = uipanel(app.LoopShapingGUI);
            app.NicholsBodePanel.Title = 'Nichols / Bode';
            app.TabGroup = uitabgroup(app.NicholsBodePanel);
            app.NicholsTab = uitab(app.TabGroup);
            app.NicholsTab.SizeChangedFcn = createCallbackFcn(app, @NicholsTabSizeChanged, true);
            app.NicholsTab.Title = 'Nichols';
            app.nicholsPlot = uiaxes(app.NicholsTab);
            title(app.nicholsPlot, 'Nichols')
            xlabel(app.nicholsPlot, 'Phase [deg]')
            ylabel(app.nicholsPlot, 'Mag [dB]')
            app.CloseLoopBodeTab = uitab(app.TabGroup);
            app.CloseLoopBodeTab.SizeChangedFcn = createCallbackFcn(app, @CloseLoopBodeTabSizeChanged, true);
            app.CloseLoopBodeTab.Title = 'Close Loop Bode';
            app.CLbodePlot = uiaxes(app.CloseLoopBodeTab);
            title(app.CLbodePlot, 'Close Loop Bode')
            xlabel(app.CLbodePlot, 'Frequency [rad/sec]')
            ylabel(app.CLbodePlot, 'Mag')
            app.OpenLoopBodeTab = uitab(app.TabGroup);
            app.OpenLoopBodeTab.SizeChangedFcn = createCallbackFcn(app, @OpenLoopBodeTabSizeChanged, true);
            app.OpenLoopBodeTab.Title = 'Open Loop Bode';
            app.OLbodePlot_mag = uiaxes(app.OpenLoopBodeTab);
            title(app.OLbodePlot_mag, 'Open Loop Mag')
            xlabel(app.OLbodePlot_mag, 'Frequency [rad/sec]')
            ylabel(app.OLbodePlot_mag, 'Mag')
            app.OLbodePlot_phase = uiaxes(app.OpenLoopBodeTab);
            title(app.OLbodePlot_phase, 'Open Loop Phase')
            xlabel(app.OLbodePlot_phase, 'Frequency [rad/sec]')
            ylabel(app.OLbodePlot_phase, 'Phase [deg]')
            app.ControllerPolesandZerosPanel = uipanel(app.LoopShapingGUI);
            app.ControllerPolesandZerosPanel.Title = 'Controller Poles and Zeros';
            app.zpkMap = uiaxes(app.ControllerPolesandZerosPanel);
            title(app.zpkMap, 'Title')
            xlabel(app.zpkMap, 'X')
            ylabel(app.zpkMap, 'Y')
            app.zpkTable_p = uitable(app.ControllerPolesandZerosPanel);
            app.zpkTable_p.ColumnName = {'Type'; 'Real Value'; 'Imag Value'};
            app.zpkTable_p.RowName = {''; ''; ''};
            app.zpkTable_p.ColumnEditable = true;
            app.zpkTable_z = uitable(app.ControllerPolesandZerosPanel);
            app.zpkTable_z.ColumnName = {'Type'; 'Real Value'; 'Imag Value'};
            app.zpkTable_z.RowName = {''; ''; ''};
            app.zpkTable_z.ColumnEditable = true;
            app.DragPolesandZerosButton = uibutton(app.ControllerPolesandZerosPanel, 'push');
            app.DragPolesandZerosButton.ButtonPushedFcn = createCallbackFcn(app, @DragPolesandZerosButtonPushed, true);
            app.DragPolesandZerosButton.Text = 'Drag Poles and Zeros';
            app.OptionsPanel = uipanel(app.LoopShapingGUI);
            app.OptionsPanel.Title = 'Options';
            app.SaveController = uibutton(app.OptionsPanel, 'push');
            app.SaveController.ButtonPushedFcn = createCallbackFcn(app, @SaveControllerButtonPushed, true);
            app.SaveController.Text = 'Save Controller To Workspace';
            app.LoadController = uibutton(app.OptionsPanel, 'push');
            app.LoadController.Text = 'Load Controller From Workspace';
            app.LoadPlant = uibutton(app.OptionsPanel, 'push');
            app.LoadPlant.ButtonPushedFcn = createCallbackFcn(app, @LoadPlantButtonPushed, true);
            app.LoadPlant.Text = 'Load Plant From Workspace';
            app.Help = uibutton(app.OptionsPanel, 'push');
            app.Help.Text = 'Help!';
            app.Discrete = uibutton(app.OptionsPanel, 'push');
            app.Discrete.Text = 'Discrete Design';
            app.savenExit = uibutton(app.OptionsPanel, 'push');
            app.savenExit.ButtonPushedFcn = createCallbackFcn(app, @savenExitButtonPushed, true);
            app.savenExit.Text = 'Save Controller to Workspace and Exit';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.LoopShapingGUILabel = uilabel(app.LoopShapingGUI);
            app.LoopShapingGUILabel.Text = 'Loop Shaping GUI';
            app.QFTLogo = uiimage(app.LoopShapingGUI);
            app.QFTLogo.ImageSource = 'logo.png';
            % Show the figure after all components are created
            app.LoopShapingGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loopShapingGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoopShapingGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoopShapingGUI)
        end
    end
end