classdef pidGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        PIDTab                matlab.ui.container.Tab
        Title                 matlab.ui.control.Label
        ParametersPanel       matlab.ui.container.Panel
        AddButton             matlab.ui.control.Button
        KpLabel               matlab.ui.control.Label
        Kp                    matlab.ui.control.EditField
        KiLabel               matlab.ui.control.Label
        Ki                    matlab.ui.control.EditField
        KdLabel               matlab.ui.control.Label
        Kd                    matlab.ui.control.EditField
        TfsLabel              matlab.ui.control.Label
        Tf                    matlab.ui.control.EditField
        ReadyLampLabel        matlab.ui.control.Label
        Ready                 matlab.ui.control.Lamp
        kp_inc                matlab.ui.control.Button
        kp_dec                matlab.ui.control.Button
        ki_inc                matlab.ui.control.Button
        ki_dec                matlab.ui.control.Button
        kd_inc                matlab.ui.control.Button
        kd_dec                matlab.ui.control.Button
        tf_inc                matlab.ui.control.Button
        tf_dec                matlab.ui.control.Button
        ContollerBodePanel    matlab.ui.container.Panel
        Bode_mag              matlab.ui.control.UIAxes
        Bode_phase            matlab.ui.control.UIAxes
        kpkiskdsTfs1Label     matlab.ui.control.Label
        OptionsPanel          matlab.ui.container.Panel
        HelpButton            matlab.ui.control.Button
        SaveExitButton        matlab.ui.control.Button
        CancelButton          matlab.ui.control.Button
        sPIDTab_s             matlab.ui.container.Tab
        Title_s               matlab.ui.control.Label
        ParametersPanel_s     matlab.ui.container.Panel
        add_s                 matlab.ui.control.Button
        KpLabel_2             matlab.ui.control.Label
        Kp_s                  matlab.ui.control.EditField
        TiLabel               matlab.ui.control.Label
        Ti                    matlab.ui.control.EditField
        TdLabel               matlab.ui.control.Label
        Td                    matlab.ui.control.EditField
        NLabel                matlab.ui.control.Label
        N                     matlab.ui.control.EditField
        ReadyLampLabel_2      matlab.ui.control.Label
        Ready_s               matlab.ui.control.Lamp
        skp_inc               matlab.ui.control.Button
        skp_dec               matlab.ui.control.Button
        Ti_inc                matlab.ui.control.Button
        Ti_dec                matlab.ui.control.Button
        Td_inc                matlab.ui.control.Button
        Td_dec                matlab.ui.control.Button
        N_inc                 matlab.ui.control.Button
        N_dec                 matlab.ui.control.Button
        OptionsPanel_s        matlab.ui.container.Panel
        SaveExitButton_s      matlab.ui.control.Button
        CancelButton_s        matlab.ui.control.Button
        ContollerBodePanel_s  matlab.ui.container.Panel
        Bode_mag_s            matlab.ui.control.UIAxes
        Bode_phase_s          matlab.ui.control.UIAxes
        kp11TisTds1sNLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
        G
        des
        kp;
        kp_s;
        kd;
        td;
        ki;
        ti;
        tff;
        n;
        edit_flag; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, G, des, EditMode)
           app.CallingApp = mainapp;
           app.G = G;
           app.des = des;
           app.edit_flag = EditMode; 
           
           app.Kp.Value = num2str(2);
           app.Ki.Value = num2str(1);
           app.Kd.Value = num2str(0.5);
           app.Tf.Value = num2str(0.2);
           
           app.Kp_s.Value = num2str(2);
           app.Ti.Value = num2str(0.5);
           app.Td.Value = num2str(0.5);
           app.N.Value = num2str(0.2);
           
           
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            choose_type = 0; % PID and not Standard PID
            cpid = qctrl.pid(str2double(app.Kp.Value),str2double(app.Ki.Value),str2double(app.Kd.Value),str2double(app.Tf.Value),[],[],[],choose_type);
            
            w0 = app.des.nom.frequency;
            c_nicresp = n2c(cpid.nicresp(w0));
            mag = mag2db(abs(c_nicresp));
            phase_local = rad2deg(angle(c_nicresp));
            
            semilogx(app.Bode_mag,w0,mag);
            semilogx(app.Bode_phase,w0,phase_local);
            app.kp = str2double(app.Kp.Value);
            app.ki = str2double(app.Ki.Value);
            app.kd = str2double(app.Kd.Value);
            app.tff = str2double(app.Tf.Value);
            
            if ~isempty(app.kp) && ~isempty(app.ki) && ~isempty(app.kd) && ~isempty(app.tff)
                app.Ready.Color = [0,1,0];
            end
            
            pid_flag = 1;
            edit_mode = 100;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp,app.ki,app.kd,app.tff,[],[],[],choose_type,pid_flag,edit_mode)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            pid_flag = 0;
            edit_mode = 0;
            choose_type = 0;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp,app.ki,app.kd,app.tff,[],[],[],choose_type,pid_flag,edit_mode)
            delete(app);
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            pid_flag =  1;
            edit_mode = 0;
            choose_type = 0;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp,app.ki,app.kd,app.tff,[],[],[],choose_type,pid_flag,edit_mode)
            delete(app);
        end

        % Button pushed function: add_s
        function add_sPushed(app, event)
            choose_type = 1;
            cpid = qctrl.pid(str2double(app.Kp_s.Value),[],[],[],str2double(app.Ti.Value),str2double(app.Td.Value),str2double(app.N.Value),choose_type);
            
            w0 = app.des.nom.frequency;
            c_nicresp = n2c(cpid.nicresp(w0));
            mag = mag2db(abs(c_nicresp));
            phase_local = rad2deg(angle(c_nicresp));
            
            semilogx(app.Bode_mag_s,w0,mag);
            semilogx(app.Bode_phase_s,w0,phase_local);
            app.kp_s = str2double(app.Kp_s.Value);
            app.ti = str2double(app.Ti.Value);
            app.td = str2double(app.Td.Value);
            app.n = str2double(app.N.Value);
            
            if ~isempty(app.kp_s) && ~isempty(app.ti) && ~isempty(app.td) && ~isempty(app.n)
                app.Ready_s.Color = [0,1,0];
            end
            pid_flag = 1;
            edit_mode = 100;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp_s,[],[],[],app.ti,app.td,app.n,choose_type,pid_flag,edit_mode)
        end

        % Button pushed function: CancelButton_s
        function CancelButton_sPushed(app, event)
            pid_flag = 0;
            choose_type = 1;
            edit_mode = 0;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp_s,[],[],[],app.ti,app.td,app.n,choose_type,pid_flag,edit_mode)
            delete(app);
        end

        % Button pushed function: SaveExitButton_s
        function SaveExitButton_sPushed(app, event)
            pid_flag = 1;
            edit_mode = 0;
            choose_type = 1;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp_s,[],[],[],app.ti,app.td,app.n,choose_type,pid_flag,edit_mode)
            delete(app);   
        end

        % Button pushed function: kp_inc
        function kp_incButtonPushed(app, event)
             value = str2double(app.Kp.Value);
             app.Kp.Value = num2str(value + 0.5);
        end

        % Button pushed function: kp_dec
        function kp_decButtonPushed(app, event)
            value = str2double(app.Kp.Value);
            app.Kp.Value = num2str(value- 0.5);
        end

        % Button pushed function: ki_inc
        function ki_incButtonPushed(app, event)
            value = str2double(app.Ki.Value);
            app.Ki.Value = num2str(value + 0.5);
        end

        % Button pushed function: ki_dec
        function ki_decButtonPushed(app, event)
            value = str2double(app.Ki.Value);
            app.Ki.Value = num2str(value - 0.5);
        end

        % Button pushed function: kd_inc
        function kd_incButtonPushed(app, event)
            value = str2double(app.Kd.Value);
            app.Kd.Value = num2str(value + 0.5);
        end

        % Button pushed function: kd_dec
        function kd_decButtonPushed(app, event)
            value= str2double(app.Kd.Value);
            app.Kd.Value = num2str(value - 0.5);
        end

        % Button pushed function: tf_inc
        function tf_incButtonPushed(app, event)
            value = str2double(app.Tf.Value);
            app.Tf.Value = num2str(value+0.1);
        end

        % Button pushed function: tf_dec
        function tf_decButtonPushed(app, event)
            value = str2double(app.Tf.Value);
            app.Tf.Value = num2str(value*0.8);
        end

        % Button pushed function: skp_inc
        function skp_incButtonPushed(app, event)
            value = str2double(app.Kp_s.Value);      
            app.Kp_s.Value = num2str(value + 0.5);
        end

        % Button pushed function: skp_dec
        function skp_decButtonPushed(app, event)
            value = str2double(app.Kp_s.Value);
            app.Kp_s.Value = num2str(value - 0.5);
        end

        % Button pushed function: Ti_inc
        function Ti_incButtonPushed(app, event)
            value = str2double(app.Ti.Value);
            app.Ti.Value = num2str(value + 0.5);
        end

        % Button pushed function: Ti_dec
        function Ti_decButtonPushed(app, event)
            value = str2double(app.Ti.Value);
            app.Ti.Value = num2str(value - 0.5);
        end

        % Button pushed function: Td_inc
        function Td_incButtonPushed(app, event)
            value = str2double(app.Td.Value);
            app.Td.Value = num2str(value + 0.5);
        end

        % Button pushed function: Td_dec
        function Td_decButtonPushed(app, event)
            value = str2double(app.Td.Value);
            app.Td.Value = num2str(value - 0.5);
        end

        % Button pushed function: N_inc
        function N_incButtonPushed(app, event)
            value = str2double(app.N.Value);
            app.N.Value = num2str(value + 0.1);
        end

        % Button pushed function: N_dec
        function N_decButtonPushed(app, event)
            value = str2double(app.N.Value);
            app.N.Value = num2str(value*0.8);
        end

        % Callback function
        function std_incButtonPushed(app, event)
            
        end

        % Callback function
        function std_decButtonPushed(app, event)
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            helpGUI('pid')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PIDTab = uitab(app.TabGroup);
            app.PIDTab.Title = 'P.I.D';
            app.Title = uilabel(app.PIDTab);
            app.Title.Text = 'PID Controller';
            app.ParametersPanel = uipanel(app.PIDTab);
            app.ParametersPanel.Title = 'Parameters';
            app.AddButton = uibutton(app.ParametersPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.KpLabel = uilabel(app.ParametersPanel);
            app.KpLabel.Text = 'Kp:';
            app.Kp = uieditfield(app.ParametersPanel, 'text');
            app.KiLabel = uilabel(app.ParametersPanel);
            app.KiLabel.Text = 'Ki:';
            app.Ki = uieditfield(app.ParametersPanel, 'text');
            app.KdLabel = uilabel(app.ParametersPanel);
            app.KdLabel.Text = 'Kd:';
            app.Kd = uieditfield(app.ParametersPanel, 'text');
            app.TfsLabel = uilabel(app.ParametersPanel);
            app.TfsLabel.Text = 'Tf [s]:';
            app.Tf = uieditfield(app.ParametersPanel, 'text');
            app.ReadyLampLabel = uilabel(app.ParametersPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.Ready = uilamp(app.ParametersPanel);
            app.kp_inc = uibutton(app.ParametersPanel, 'push');
            app.kp_inc.ButtonPushedFcn = createCallbackFcn(app, @kp_incButtonPushed, true);
            app.kp_inc.Text = '+';
            app.kp_dec = uibutton(app.ParametersPanel, 'push');
            app.kp_dec.ButtonPushedFcn = createCallbackFcn(app, @kp_decButtonPushed, true);
            app.kp_dec.Text = '-';
            app.ki_inc = uibutton(app.ParametersPanel, 'push');
            app.ki_inc.ButtonPushedFcn = createCallbackFcn(app, @ki_incButtonPushed, true);
            app.ki_inc.Text = '+';
            app.ki_dec = uibutton(app.ParametersPanel, 'push');
            app.ki_dec.ButtonPushedFcn = createCallbackFcn(app, @ki_decButtonPushed, true);
            app.ki_dec.Text = '-';
            app.kd_inc = uibutton(app.ParametersPanel, 'push');
            app.kd_inc.ButtonPushedFcn = createCallbackFcn(app, @kd_incButtonPushed, true);
            app.kd_inc.Text = '+';
            app.kd_dec = uibutton(app.ParametersPanel, 'push');
            app.kd_dec.ButtonPushedFcn = createCallbackFcn(app, @kd_decButtonPushed, true);
            app.kd_dec.Text = '-';
            app.tf_inc = uibutton(app.ParametersPanel, 'push');
            app.tf_inc.ButtonPushedFcn = createCallbackFcn(app, @tf_incButtonPushed, true);
            app.tf_inc.Text = '+';
            app.tf_dec = uibutton(app.ParametersPanel, 'push');
            app.tf_dec.ButtonPushedFcn = createCallbackFcn(app, @tf_decButtonPushed, true);
            app.tf_dec.Text = '-';
            app.ContollerBodePanel = uipanel(app.PIDTab);
            app.ContollerBodePanel.Title = 'Contoller Bode';
            app.Bode_mag = uiaxes(app.ContollerBodePanel);
            title(app.Bode_mag, 'Mag')
            xlabel(app.Bode_mag, 'Frequency [rad/sec]')
            ylabel(app.Bode_mag, 'Mag')
            app.Bode_phase = uiaxes(app.ContollerBodePanel);
            title(app.Bode_phase, 'Phase')
            xlabel(app.Bode_phase, 'Frequency [rad/sec]')
            ylabel(app.Bode_phase, 'Phase')
            app.kpkiskdsTfs1Label = uilabel(app.PIDTab);
            app.kpkiskdsTfs1Label.Text = 'kp+ki/s+(kd*s)/(Tf*s+1)';
            app.OptionsPanel = uipanel(app.PIDTab);
            app.OptionsPanel.Title = 'Options';
            app.HelpButton = uibutton(app.OptionsPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.SaveExitButton = uibutton(app.OptionsPanel, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.sPIDTab_s = uitab(app.TabGroup);
            app.sPIDTab_s.Title = ' Standard P.I.D';
            app.Title_s = uilabel(app.sPIDTab_s);
            app.Title_s.Text = 'Standard PID Controller';
            app.ParametersPanel_s = uipanel(app.sPIDTab_s);
            app.ParametersPanel_s.Title = 'Parameters';
            app.add_s = uibutton(app.ParametersPanel_s, 'push');
            app.add_s.ButtonPushedFcn = createCallbackFcn(app, @add_sPushed, true);
            app.add_s.Text = 'Add';
            app.KpLabel_2 = uilabel(app.ParametersPanel_s);
            app.KpLabel_2.Text = 'Kp:';
            app.Kp_s = uieditfield(app.ParametersPanel_s, 'text');
            app.TiLabel = uilabel(app.ParametersPanel_s);
            app.TiLabel.Text = 'Ti:';
            app.Ti = uieditfield(app.ParametersPanel_s, 'text');
            app.TdLabel = uilabel(app.ParametersPanel_s);
            app.TdLabel.Text = 'Td';
            app.Td = uieditfield(app.ParametersPanel_s, 'text');
            app.NLabel = uilabel(app.ParametersPanel_s);
            app.NLabel.Text = 'N';
            app.N = uieditfield(app.ParametersPanel_s, 'text');
            app.ReadyLampLabel_2 = uilabel(app.ParametersPanel_s);
            app.ReadyLampLabel_2.Text = 'Ready';
            app.Ready_s = uilamp(app.ParametersPanel_s);
            app.skp_inc = uibutton(app.ParametersPanel_s, 'push');
            app.skp_inc.ButtonPushedFcn = createCallbackFcn(app, @skp_incButtonPushed, true);
            app.skp_inc.Text = '+';
            app.skp_dec = uibutton(app.ParametersPanel_s, 'push');
            app.skp_dec.ButtonPushedFcn = createCallbackFcn(app, @skp_decButtonPushed, true);
            app.skp_dec.Text = '-';
            app.Ti_inc = uibutton(app.ParametersPanel_s, 'push');
            app.Ti_inc.ButtonPushedFcn = createCallbackFcn(app, @Ti_incButtonPushed, true);
            app.Ti_inc.Text = '+';
            app.Ti_dec = uibutton(app.ParametersPanel_s, 'push');
            app.Ti_dec.ButtonPushedFcn = createCallbackFcn(app, @Ti_decButtonPushed, true);
            app.Ti_dec.Text = '-';
            app.Td_inc = uibutton(app.ParametersPanel_s, 'push');
            app.Td_inc.ButtonPushedFcn = createCallbackFcn(app, @Td_incButtonPushed, true);
            app.Td_inc.Text = '+';
            app.Td_dec = uibutton(app.ParametersPanel_s, 'push');
            app.Td_dec.ButtonPushedFcn = createCallbackFcn(app, @Td_decButtonPushed, true);
            app.Td_dec.Text = '-';
            app.N_inc = uibutton(app.ParametersPanel_s, 'push');
            app.N_inc.ButtonPushedFcn = createCallbackFcn(app, @N_incButtonPushed, true);
            app.N_inc.Text = '+';
            app.N_dec = uibutton(app.ParametersPanel_s, 'push');
            app.N_dec.ButtonPushedFcn = createCallbackFcn(app, @N_decButtonPushed, true);
            app.N_dec.Text = '-';
            app.OptionsPanel_s = uipanel(app.sPIDTab_s);
            app.OptionsPanel_s.Title = 'Options';
            app.SaveExitButton_s = uibutton(app.OptionsPanel_s, 'push');
            app.SaveExitButton_s.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButton_sPushed, true);
            app.SaveExitButton_s.Text = 'Save & Exit';
            app.CancelButton_s = uibutton(app.OptionsPanel_s, 'push');
            app.CancelButton_s.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_sPushed, true);
            app.CancelButton_s.Text = 'Cancel';
            app.ContollerBodePanel_s = uipanel(app.sPIDTab_s);
            app.ContollerBodePanel_s.Title = 'Contoller Bode';
            app.Bode_mag_s = uiaxes(app.ContollerBodePanel_s);
            title(app.Bode_mag_s, 'Mag')
            xlabel(app.Bode_mag_s, 'Frequency [rad/sec]')
            ylabel(app.Bode_mag_s, 'Mag')
            app.Bode_phase_s = uiaxes(app.ContollerBodePanel_s);
            title(app.Bode_phase_s, 'Phase')
            xlabel(app.Bode_phase_s, 'Frequency [rad/sec]')
            ylabel(app.Bode_phase_s, 'Phase')
            app.kp11TisTds1sNLabel = uilabel(app.sPIDTab_s);
            app.kp11TisTds1sNLabel.Text = 'kp(1+1/(Ti*s)+Td*s/(1+s/N))';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pidGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end